classdef groundcalc_chart_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GroundCalcchartscontrolUIFigure  matlab.ui.Figure
        CoverlayerresistivitymEditFieldLabel  matlab.ui.control.Label
        CoverlayerresistivitymEditField  matlab.ui.control.NumericEditField
        CoverlayerthicknesscmEditFieldLabel  matlab.ui.control.Label
        CoverlayerthicknesscmEditField  matlab.ui.control.NumericEditField
        FaultdurationmsEditFieldLabel   matlab.ui.control.Label
        FaultdurationmsEditField        matlab.ui.control.NumericEditField
        ExitButton                      matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        TouchvoltagefigurehandleEditFieldLabel  matlab.ui.control.Label
        TouchvoltagefigurehandleEditField  matlab.ui.control.NumericEditField
        StepvoltagefigurehandleEditFieldLabel  matlab.ui.control.Label
        StepvoltagefigurehandleEditField  matlab.ui.control.NumericEditField
        MaximumallowableEtVEditFieldLabel  matlab.ui.control.Label
        MaximumallowableEtVEditField    matlab.ui.control.NumericEditField
        MaximumallowableEpVEditFieldLabel  matlab.ui.control.Label
        MaximumallowableEpVEditField    matlab.ui.control.NumericEditField
        ToplayerresistivitymEditFieldLabel  matlab.ui.control.Label
        ToplayerresistivitymEditField   matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            try
            t_fault=app.FaultdurationmsEditField.Value/1000;
            rho_cover=app.CoverlayerresistivitymEditField.Value;
            h_cover=app.CoverlayerthicknesscmEditField.Value / 100;
            rho_top=app.ToplayerresistivitymEditField.Value;
            
            Ib=0.116/sqrt(t_fault);
            
            if(h_cover==0)
                Cs=1;
                R2Fs=6*Cs*rho_top;
                R2Fp=1.5*Cs*rho_top;
            else
                Cs=1-(0.106*((1-(rho_top/rho_cover))/(2*h_cover+0.106)));
                R2Fs=6*Cs*rho_cover;
                R2Fp=1.5*Cs*rho_cover;
            end
            Rb=1000;
            Ep_max=(Rb+R2Fs)*Ib;
            Et_max=(Rb+R2Fp)*Ib;
            
            app.MaximumallowableEtVEditField.Value=Et_max;
            app.MaximumallowableEpVEditField.Value=Ep_max;
            
            fig=app.TouchvoltagefigurehandleEditField.Value;
            figure(fig);
            vmin=Et_max;
            vmax=max(caxis);
            if vmax<vmin
                vmax=2*vmin;
            end
            caxis([vmin vmax])
            
            fig=app.StepvoltagefigurehandleEditField.Value;
            figure(fig);
            vmin=Ep_max;
            vmax=max(caxis);
            if vmax<vmin
                vmax=2*vmin;
            end
            caxis([vmin vmax])
            
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GroundCalcchartscontrolUIFigure = uifigure;
            app.GroundCalcchartscontrolUIFigure.Name = 'GroundCalc charts control';
            app.CoverlayerresistivitymEditFieldLabel = uilabel(app.GroundCalcchartscontrolUIFigure);
            app.CoverlayerresistivitymEditFieldLabel.Text = 'Cover layer resistivity [Ω.m]:';
            app.CoverlayerresistivitymEditField = uieditfield(app.GroundCalcchartscontrolUIFigure, 'numeric');
            app.CoverlayerresistivitymEditField.Limits = [0 Inf];
            app.CoverlayerresistivitymEditField.Value = 3000;
            app.CoverlayerthicknesscmEditFieldLabel = uilabel(app.GroundCalcchartscontrolUIFigure);
            app.CoverlayerthicknesscmEditFieldLabel.Text = 'Cover layer thickness [cm]:';
            app.CoverlayerthicknesscmEditField = uieditfield(app.GroundCalcchartscontrolUIFigure, 'numeric');
            app.CoverlayerthicknesscmEditField.Limits = [0 Inf];
            app.FaultdurationmsEditFieldLabel = uilabel(app.GroundCalcchartscontrolUIFigure);
            app.FaultdurationmsEditFieldLabel.Text = 'Fault duration [ms]:';
            app.FaultdurationmsEditField = uieditfield(app.GroundCalcchartscontrolUIFigure, 'numeric');
            app.FaultdurationmsEditField.Limits = [0 Inf];
            app.FaultdurationmsEditField.Value = 1000;
            app.ExitButton = uibutton(app.GroundCalcchartscontrolUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SetButton = uibutton(app.GroundCalcchartscontrolUIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.TouchvoltagefigurehandleEditFieldLabel = uilabel(app.GroundCalcchartscontrolUIFigure);
            app.TouchvoltagefigurehandleEditFieldLabel.Text = 'Touch voltage figure handle:';
            app.TouchvoltagefigurehandleEditField = uieditfield(app.GroundCalcchartscontrolUIFigure, 'numeric');
            app.TouchvoltagefigurehandleEditField.Limits = [0 Inf];
            app.TouchvoltagefigurehandleEditField.ValueDisplayFormat = '%.0f';
            app.TouchvoltagefigurehandleEditField.Value = 2;
            app.StepvoltagefigurehandleEditFieldLabel = uilabel(app.GroundCalcchartscontrolUIFigure);
            app.StepvoltagefigurehandleEditFieldLabel.Text = 'Step voltage figure handle:';
            app.StepvoltagefigurehandleEditField = uieditfield(app.GroundCalcchartscontrolUIFigure, 'numeric');
            app.StepvoltagefigurehandleEditField.Limits = [0 Inf];
            app.StepvoltagefigurehandleEditField.ValueDisplayFormat = '%.0f';
            app.StepvoltagefigurehandleEditField.Value = 3;
            app.MaximumallowableEtVEditFieldLabel = uilabel(app.GroundCalcchartscontrolUIFigure);
            app.MaximumallowableEtVEditFieldLabel.Text = 'Maximum allowable Et [V]';
            app.MaximumallowableEtVEditField = uieditfield(app.GroundCalcchartscontrolUIFigure, 'numeric');
            app.MaximumallowableEtVEditField.Limits = [0 Inf];
            app.MaximumallowableEtVEditField.Editable = 'off';
            app.MaximumallowableEpVEditFieldLabel = uilabel(app.GroundCalcchartscontrolUIFigure);
            app.MaximumallowableEpVEditFieldLabel.Text = 'Maximum allowable Ep [V]';
            app.MaximumallowableEpVEditField = uieditfield(app.GroundCalcchartscontrolUIFigure, 'numeric');
            app.MaximumallowableEpVEditField.Limits = [0 Inf];
            app.MaximumallowableEpVEditField.Editable = 'off';
            app.ToplayerresistivitymEditFieldLabel = uilabel(app.GroundCalcchartscontrolUIFigure);
            app.ToplayerresistivitymEditFieldLabel.Text = 'Top layer resistivity [Ω.m]:';
            app.ToplayerresistivitymEditField = uieditfield(app.GroundCalcchartscontrolUIFigure, 'numeric');
            app.ToplayerresistivitymEditField.Limits = [0 Inf];
            app.ToplayerresistivitymEditField.Value = 1000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = groundcalc_chart_control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GroundCalcchartscontrolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GroundCalcchartscontrolUIFigure)
        end
    end
end