classdef groundcalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GroundCalc                      matlab.ui.Figure
        ReturncurrentAPanel             matlab.ui.container.Panel
        Iret_re                         matlab.ui.control.NumericEditField
        jLabel_2                        matlab.ui.control.Label
        Iret_im                         matlab.ui.control.NumericEditField
        EnergizationcurrentAPanel       matlab.ui.container.Panel
        I_re                            matlab.ui.control.NumericEditField
        jLabel                          matlab.ui.control.Label
        I_im                            matlab.ui.control.NumericEditField
        ExitButton                      matlab.ui.control.Button
        LoaddataButton                  matlab.ui.control.Button
        SavedataButton                  matlab.ui.control.Button
        SoilresistivityPanel            matlab.ui.container.Panel
        ToplayerthicknessmEditField     matlab.ui.control.NumericEditField
        ToplayerthicknessmEditFieldLabel  matlab.ui.control.Label
        BottomlayerresistivitymEditField  matlab.ui.control.NumericEditField
        BottomlayerresistivitymLabel    matlab.ui.control.Label
        ToplayerresistivitymEditField   matlab.ui.control.NumericEditField
        ToplayerresistivitymLabel       matlab.ui.control.Label
        PreviewPanel                    matlab.ui.container.Panel
        RefreshPreview                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        SystemTab                       matlab.ui.container.Tab
        ConductordataPanel              matlab.ui.container.Panel
        ReturnDropDown                  matlab.ui.control.DropDown
        ReturnDropDownLabel             matlab.ui.control.Label
        RetardedValueEditField          matlab.ui.control.EditField
        RadiusmEditFieldLabel           matlab.ui.control.Label
        RetardedUnitDropDown            matlab.ui.control.DropDown
        Convert2RadiusButton            matlab.ui.control.Button
        SourceconductorDropDown         matlab.ui.control.DropDown
        SourceconductorDropDownLabel    matlab.ui.control.Label
        SubdivisionlengthmEditField     matlab.ui.control.NumericEditField
        SubdivisionlengthmEditFieldLabel  matlab.ui.control.Label
        defaultannealedcopperLabel      matlab.ui.control.Label
        BasemEditField                  matlab.ui.control.NumericEditField
        BasemLabel                      matlab.ui.control.Label
        RadiusmEditField                matlab.ui.control.NumericEditField
        SourcefileEditField             matlab.ui.control.EditField
        SourcefileEditFieldLabel        matlab.ui.control.Label
        LoadCondButton                  matlab.ui.control.Button
        GeneralPanel                    matlab.ui.container.Panel
        ProjectdescriptionTextArea      matlab.ui.control.TextArea
        ProjectdescriptionLabel         matlab.ui.control.Label
        JobIDEditField                  matlab.ui.control.EditField
        JobIDLabel                      matlab.ui.control.Label
        WorkdirectoryEditField          matlab.ui.control.EditField
        WorkdirectoryEditFieldLabel     matlab.ui.control.Label
        LoadWorkdirButton               matlab.ui.control.Button
        SanitycheckButton               matlab.ui.control.Button
        row2Delete                      matlab.ui.control.NumericEditField
        CurrentrowLabel                 matlab.ui.control.Label
        DeleterowButton                 matlab.ui.control.Button
        AddrowButton                    matlab.ui.control.Button
        Load2TableButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ComputationsTab                 matlab.ui.container.Tab
        ComputationengineoptionsPanel   matlab.ui.container.Panel
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        RebuildcircuitmodelfromscratchCheckBox  matlab.ui.control.CheckBox
        UsepointsourcemodelCheckBox     matlab.ui.control.CheckBox
        AssumeequipotentialgridCheckBox  matlab.ui.control.CheckBox
        RecalculatesafevaluesButton     matlab.ui.control.Button
        ClearmessagesButton             matlab.ui.control.Button
        ProgrammessagesTextArea         matlab.ui.control.TextArea
        ProgrammessagesTextAreaLabel    matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        MiscPanel                       matlab.ui.container.Panel
        SavetomatfileCheckBox           matlab.ui.control.CheckBox
        ShowgridCheckBox                matlab.ui.control.CheckBox
        SuppressmessagesCheckBox        matlab.ui.control.CheckBox
        RequestplotsPanel               matlab.ui.container.Panel
        GridoffsetmEditField            matlab.ui.control.NumericEditField
        GridoffsetmEditFieldLabel       matlab.ui.control.Label
        DeltaEditField                  matlab.ui.control.NumericEditField
        PointspacingmLabel              matlab.ui.control.Label
        RefreshplotsButton              matlab.ui.control.Button
        StepvoltagesCheckBox            matlab.ui.control.CheckBox
        TouchvoltagesCheckBox           matlab.ui.control.CheckBox
        CurrentdistributionCheckBox     matlab.ui.control.CheckBox
        SurfacepotentialsCheckBox       matlab.ui.control.CheckBox
        SafelimitsPanel                 matlab.ui.container.Panel
        FaultdurationmsEditField        matlab.ui.control.NumericEditField
        FaultdurationmsEditFieldLabel   matlab.ui.control.Label
        CoverlayerthicknesscmEditField  matlab.ui.control.NumericEditField
        CoverlayerthicknesscmEditFieldLabel  matlab.ui.control.Label
        CoverlayerresistivitymEditField  matlab.ui.control.NumericEditField
        CoverlayerresistivitymLabel     matlab.ui.control.Label
    end


    methods (Access = private)
        
        function [isgood, msg] = validateWorkdir(app)
            fname=app.WorkdirectoryEditField.Value;
            isgood=true;
            msg='';
            if  ~exist(fname, 'dir')
                msg='Invalid work directory!';
                isgood=false;
                return;
            end
        end

        function [isgood, errorMsg, i] = validateDataTable(app)
        tableData = app.UITable.Data;


            % Convert cell array to numeric array, setting NaN for empty cells
            tableDataNumeric = NaN(size(tableData));
            for i = 1:numel(tableData)
                if ~isempty(tableData{i})
                    tableDataNumeric(i) = tableData{i};
                end
            end

            % Check for empty table or incomplete rows
            if isempty(tableDataNumeric) || any(any(isnan(tableDataNumeric)))
                errorMsg = 'Non-numeric or empty data found.';
%                 msgbox(['Your data defies the laws of physics. ', errorMsg], 'You lose, fella.', 'error');
                isgood=false;i=[];
                return; % Bail out, nothing to see here
            end
            
            isgood=true;
            % Initialize an error message
            errorMsg = '';

            %             Rule 0: Check for duplicate rows (overlapping lines)
            if size(unique(tableDataNumeric(:,1:6), 'rows'), 1) < size(tableDataNumeric(:,1:6), 1)
                errorMsg = 'Overlapping lines detected. Each line must be unique.';
                isgood=false;i=[];
                return;
            else
                % Loop over each row for other checks
                for i = 1:size(tableDataNumeric, 1)
                    row = tableDataNumeric(i, :);

                    % Rule 1: Z-coordinate at least 10x the external radius
                    z_start = row(3);
                    z_end = row(6);
                    rad_ext = row(8);

                    if abs(z_start) < 10 * rad_ext || abs(z_end) < 10 * rad_ext
                        errorMsg = 'Z-coordinate must be at least 10 times the external radius.';
                        isgood=false;
                        return;
                    end

                    % Rule 2: Line must be perfectly horizontal or vertical
                    dx = row(4) - row(1);
                    dy = row(5) - row(2);
                    dz = row(6) - row(3);

                    if ~(dx == 0 && dy == 0) && ~(dy == 0 && dz == 0) && ~(dz == 0 && dx == 0)
                        errorMsg = 'Lines must be perfectly horizontal or vertical. No vertical obliquities. No exceptions.';
                        isgood=false;
                        return;
                    end

                    % Rule 3: Z-coordinates must always be negative
                    if z_start >= 0 || z_end >= 0
                        errorMsg = 'Z-coordinates must be negative, because... grounding.';
                        isgood=false;
                        return;
                    end

                    % Rule 4: Conductor directionality
                    if (dx > 0 && row(4) < row(1)) || (dz < 0 && abs(z_end) < abs(z_start))
                        errorMsg = 'Check the directionality of your conductors. Start coordinates must always be less than the corresponding end coordinates.';
                        return;
                    end

                    % Rule 5: Inner and outer radius checks
                    rad_in = row(7);
                    if rad_in < 0 || rad_ext <= 0 || rad_ext <= rad_in
                        errorMsg = 'Check your radius values. Inner radius must be less than outer radius, and outer radius cannot be zero.';
                        isgood=false;
                        return;
                    end

                    % Rule: No zero-length segments
                    if (dx == 0 && dy == 0 && dz == 0)
                        errorMsg = 'Zero-length segments are about as useful as a chocolate teapot. Try again.';
                        isgood=false;
                        return;
                    end

                end
            end
        end

        function [isgood, msg] = validateFilename(app)
            fname=app.SourcefileEditField.Value;
            isgood=true;
            msg='';
            if  ~exist(fname, 'file')
                msg='Invalid file name!';
                isgood=false;
                return;
            end
        end
        
        function [results, msg] = loadFromFile(app,fname)
            results=[];
            msg=[];
            [~,~,ext] = fileparts(fname);
            if strcmp(ext,'.dxf')
%                 addpath('functions');
                data=[];
                try
                    [c_Line,c_Poly,c_Cir,c_Arc,c_Poi] = f_LectDxf(fname);
                    if ~isempty(c_Line{1,1})
                        for i=1:size(c_Line,1)
                            data(i,:)=cell2mat(c_Line(i,1));
                        end
                    end
                catch
                    data=[];
                end
                if isempty(data)
                    msg='Unable to parse DXF data! Check that the file is valid and compatible with the format DXF 2004. Remember that only LINE objects are currently supported.';
                    return;
                end
            else
                try
                    data=importdata(fname);
                catch
                    msg='File contains invalid (non-numerical) data!';
                    return;
                end
            end

            if isstruct(data)
                results=data.data;
            else
                results=data;
            end
        end
        
        
        function out = redefRowLabels(app)
            nrows = size(app.UITable.Data,1);
            app.UITable.RowName=string(1:nrows);
            app.SourceconductorDropDown.Items=string(1:nrows);
            app.ReturnDropDown.Items=string(1:nrows);

            thisW=round(0.5903*app.GroundCalc.Position(3));
            % Total table width (excluding vertical scrollbar)
            totalWidth = thisW - 50;  % 758 pixels wide minus 50 pixels for the scrollbar

            % Number of columns (excluding the row names)
            numColumns = 10;

            % Calculate the width for each column
            equalColumnWidth = floor(totalWidth / numColumns);  % Using floor to avoid decimal pixel values

            % Create a cell array to set the 'ColumnWidth' property
            columnWidths = repmat({equalColumnWidth}, 1, numColumns);  % A cell array with equal widths

            % Apply the column widths
            app.UITable.ColumnWidth = columnWidths;

        end
    

        function [isgood, msg] = validateJobID(app)
            jobID=app.JobIDEditField.Value;
            isgood=true;
            msg='';
            BadChar = '<>:"/\|?*';
            BadName = {'CON', 'PRN', 'AUX', 'NUL', 'CLOCK$', ...
                'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', ...
                'COM7', 'COM8', 'COM9', ...
                'LPT1', 'LPT2', 'LPT3', 'LPT4', 'LPT5', 'LPT6', ...
                'LPT7', 'LPT8', 'LPT9'};
            if isempty(jobID)
                isgood=false;
                msg='You must specify a JobID!';
                return;
            else
                bad = ismember(BadChar, jobID);
                if any(bad)
                    msg = ['JobID name contains bad characters: ', BadChar(bad)];
                    isgood=false;
                    return;
                elseif any(jobID < 32)
                    msg = ['JobID name contains non printable characters, ASCII:', sprintf(' %d', jobID(jobID < 32))];
                    isgood=false;
                    return;
                elseif ~isempty(jobID) && (jobID(end) == ' ' || jobID(end) == '.')
                    msg = 'JobID name must not contain a trailing space or dot.';
                    isgood=false;
                    return;
                else
                    [~, name] = fileparts(jobID);
                    if any(strcmpi(name, BadName))
                        msg = ['JobID name not allowed: ', name];
                        isgood=false;
                        return;
                    end
                end
            end
        end

function results = saveAllData(app)

            results=false;
            %[testdir,msg]=validateWorkDir(app);
            %if ~testdir
            %    f = msgbox(msg, 'You lose, fella.','error');
            %    return;
            %end

            [testfile,msg]=validateJobID(app);
            if ~testfile
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end

            %ok now do stuff
            projID=app.JobIDEditField.Value;
            [~,wdir] = uiputfile('*.mat','Save input',[projID '.mat']);

            %             wdir=app.WorkingdirectoryEditField.Value;
            if wdir ~= 0
                projdir=fullfile(wdir);
                %             orig_state = warning;warning('off','all');
                %             mkdir(projdir); warning(orig_state);
                %             mkdir(projdir);
                fname=[projID '.mat'];
                app_data = readAllAppValues(app);
                save(fullfile(projdir,fname),'app_data');
                %             warning(orig_state);
                results=true;
            end
        end

        function results = writeAllAppValues(app,app_data)

            props = properties(app);
            
            try
                for iprop = 1:length(props)
                    thisprop = props{iprop};

                    if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                            strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                            strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                            strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable')
                        
                        if isprop(app.(thisprop),'Items')
                            app.(thisprop).Items=app_data.(thisprop).Items;
                        end

                        if isprop(app.(thisprop),'Value')
                            app.(thisprop).Value=app_data.(thisprop).Value;
                        end

                        app.(thisprop).Enable=app_data.(thisprop).Enable;
                        app.(thisprop).Visible=app_data.(thisprop).Visible;

                        if isprop(app.(thisprop),'Editable')
                            app.(thisprop).Editable=app_data.(thisprop).Editable;
                        end

                        if isprop(app.(thisprop),'List')
                            app.(thisprop).List=app_data.(thisprop).List;
                        end

                        if isprop(app.(thisprop),'Data')
                            app.(thisprop).Data=app_data.(thisprop).Data;
                        end

                    end

                end
            catch
                % do nothing, jon snow
            end
        end

        function appdata = readAllAppValues(app)
            appdata=struct;

            props = properties(app);

            for iprop = 1:length(props)
                thisprop = props{iprop};

                if strcmp(app.(thisprop).Type,'uieditfield') || strcmp(app.(thisprop).Type,'uitextarea') || ...
                        strcmp(app.(thisprop).Type,'uinumericeditfield') || strcmp(app.(thisprop).Type,'uicheckbox') || ...
                        strcmp(app.(thisprop).Type,'uispinner') || strcmp(app.(thisprop).Type,'uiradiobutton') || ...
                        strcmp(app.(thisprop).Type,'uidropdown') || strcmp(app.(thisprop).Type,'uitable')

                    appdata.(thisprop)=struct;

                    if isprop(app.(thisprop),'Value')
                        appdata.(thisprop).Value=app.(thisprop).Value;
                    end

                    appdata.(thisprop).Enable=app.(thisprop).Enable;
                    appdata.(thisprop).Visible=app.(thisprop).Visible;

                    if isprop(app.(thisprop),'Editable')
                        appdata.(thisprop).Editable=app.(thisprop).Editable;
                    end

                    if isprop(app.(thisprop),'List')
                        appdata.(thisprop).List=app.(thisprop).List;
                    end

                    if isprop(app.(thisprop),'Items')
                        appdata.(thisprop).Items=app.(thisprop).Items;
                    end


                    if isprop(app.(thisprop),'Data')
                        appdata.(thisprop).Data=app.(thisprop).Data;
                    end

                end

            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            orig_state = warning;warning('off','all');
            currentFolder = pwd;
            app.WorkdirectoryEditField.Value=pwd;
            addpath(fullfile(currentFolder,'functions'));
            warning(orig_state);

            cameratoolbar('Show','SetMode','orbit');
            newrow={[],[],[],[],[],[],0,app.RadiusmEditField.Value,1,1};
            app.UITable.ColumnFormat = {'numeric'};
            app.UITable.ColumnFormat = repmat( {'numeric'}, 1, 10 );
            app.UITable.Data=newrow;
            redefRowLabels(app);
           
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: LoadCondButton
        function LoadCondButtonPushed(app, event)

            [file,path] = uigetfile({'*.txt';'*.csv'; '*.dxf'});
            app.GroundCalc.Visible = 'off';
            app.GroundCalc.Visible = 'on';
            if file ~=0
                fname=fullfile(path,file);
                app.SourcefileEditField.Value=fname;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            [testme,msg]=validateWorkdir(app);
            if ~testme
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end

            [testfile,msg]=validateJobID(app);
            if ~testfile
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end

            [isgood, errorMsg, rownum] = validateDataTable(app);
            if ~isgood
                if ~isempty(errorMsg)
                    if isempty(rownum)
                        msg=sprintf('Data entered by the user defies the laws of physics. ');
                    else
                        msg=sprintf('Data at row %d defies the laws of physics. ',rownum);
                    end
                    msgbox([msg, errorMsg], 'You lose, fella.', 'error');
                    return;
                end
            end

            tableData = app.UITable.Data;


            % Convert cell array to numeric array, setting NaN for empty cells
            tableDataNumeric = NaN(size(tableData));
            for i = 1:numel(tableData)
                if ~isempty(tableData{i})
                    tableDataNumeric(i) = tableData{i};
                end
            end

            WORKDIR=app.WorkdirectoryEditField.Value;
            jobid=app.JobIDEditField.Value;
            cond_radius=tableDataNumeric(:,8);
            cond_rho=tableDataNumeric(:,9).*app.BasemEditField.Value;
            cond_mur=tableDataNumeric(:,10);
            coords_src=tableDataNumeric(:,1:6);
            MAX_LEN=app.SubdivisionlengthmEditField.Value;
            src_idx=str2double(app.SourceconductorDropDown.Value);
            ret_idx=str2double(app.ReturnDropDown.Value);
            delta=app.DeltaEditField.Value;
            buffer=app.GridoffsetmEditField.Value;
            rho_top=app.ToplayerresistivitymEditField.Value;
            rho_bottom=app.BottomlayerresistivitymEditField.Value;
            h_top=app.ToplayerthicknessmEditField.Value;
            freq=app.FrequencyHzEditField.Value;
            i_src=complex(app.I_re.Value,app.I_im.Value);
            i_ret=complex(app.Iret_re.Value,app.Iret_im.Value);
            t_fault=app.FaultdurationmsEditField.Value;
            rho_cover=app.CoverlayerresistivitymEditField.Value;
            h_cover=app.CoverlayerthicknesscmEditField.Value / 100;
            plot_surf=app.SurfacepotentialsCheckBox.Value;
            plot_curr=app.CurrentdistributionCheckBox.Value;
            plot_touch=app.TouchvoltagesCheckBox.Value;
            plot_step=app.StepvoltagesCheckBox.Value;
            plot_grid=app.ShowgridCheckBox.Value;
            enforce_equipotential=app.AssumeequipotentialgridCheckBox.Value;
            enforce_segmentationonly=app.UsepointsourcemodelCheckBox.Value;
            enforce_rebuild=app.RebuildcircuitmodelfromscratchCheckBox.Value;
            supress_messages=app.SuppressmessagesCheckBox.Value;
            save_mat=app.SavetomatfileCheckBox.Value;

            timestamp = @(t) datetime(t,'ConvertFrom','datenum');
            if isempty(app.ProgrammessagesTextArea.Value{1})
                app.ProgrammessagesTextArea.Value{1} = [sprintf('%s: Process started.',timestamp(now))];
            else
                app.ProgrammessagesTextArea.Value{end+1} = [sprintf('%s: Process started.',timestamp(now))];
            end


            try
%                 close all;
                clc;
%                 addpath('functions');
                runGroundCalcEngine
                app.RefreshplotsButton.Enable='on';
            catch ME
                f = msgbox('Error running GroundCalc solver!','You lose, fella.','error');
                app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Some weird thing happened, probably due to the fact that no one pays for this. Here''s the MATLAB exception stack in case you know what to do with it:\nIdentifier: %s\nMessage: %s\n',ME.identifier,ME.message)];
                for k=1:numel(ME.stack)
                    app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Name:%s\nLine: %d',ME.stack(k).name,ME.stack(k).line)];
                end
 
            end
        end

        % Button pushed function: RefreshplotsButton
        function RedefineplotsButtonPushed(app, event)
            t_fault=app.FaultdurationmsEditField.Value/1000;
            rho_cover=app.CoverlayerresistivitymEditField.Value;
            h_cover=app.CoverlayerthicknesscmEditField.Value / 100;
            rho_top=app.ToplayerresistivitymEditField.Value;
            
           
            Ib=0.116/sqrt(t_fault);
            
            if(h_cover==0)
                Cs=1;
                R2Fs=6*Cs*rho_top;
                R2Fp=1.5*Cs*rho_top;
            else
                Cs=1-(0.106*((1-(rho_top/rho_cover))/(2*h_cover+0.106)));
                R2Fs=6*Cs*rho_cover;
                R2Fp=1.5*Cs*rho_cover;
            end
            Rb=1000;
            Ep_max=(Rb+R2Fs)*Ib;
            Et_max=(Rb+R2Fp)*Ib;
            
            figure(2);
            vmin=Et_max;
            vmax=max(caxis);
            if vmax<vmin
                vmax=2*vmin;
            end
            caxis([vmin vmax]);
            title(sprintf('Touch voltages distribution, E_{t,lim} = %1.0f V',Et_max));
            
            figure(3);
            vmin=Ep_max;
            vmax=max(caxis);
            if vmax<vmin
                vmax=2*vmin;
            end
            caxis([vmin vmax]);
            title(sprintf('Step voltages distribution, E_{p,lim} = %1.0f V',Ep_max));
        end

        % Button pushed function: Load2TableButton
        function Load2TableButtonPushed(app, event)
            [testfile,msg]=validateFilename(app);
            if ~testfile
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            fname=app.SourcefileEditField.Value;
            
            [fdata,msg]=loadFromFile(app,fname);
            
            if isempty(fdata)
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            else
                nrows=size(fdata,1);
                ncols=size(fdata,2);
                if ncols==6
                    std_rad=app.RadiusmEditField.Value*ones(1,nrows)';
                elseif ncols==7
                    std_rad=fdata(:,7);
                else
                    msg = 'Invalid file structure - input file must contain 6 or 7 numeric columns!';
                    f = msgbox(msg, 'You lose, fella.','error');
                    return;
                end
                fdata = [fdata(:,1:6) zeros(1,nrows)' std_rad ones(1,nrows)' ones(1,nrows)'];
                app.UITable.Data=num2cell(fdata);
                redefRowLabels(app);
                RefreshPreviewButtonPushed(app);
                
            end
            
        end

        % Button pushed function: RefreshPreview
        function RefreshPreviewButtonPushed(app, event)
            % 1. Extract Data from UITable
            tableData = app.UITable.Data; % Replace 'app.UITable' with your UITable's name

            % Attempt to Convert the cell array to a numeric matrix
            tableDataNumeric = cell2mat(tableData(:,1:6));

            % Initialize the UIAxes for 3D plotting
            cla(app.UIAxes); % Clear previous plots
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            view(app.UIAxes, 3);

            % 2. Check for empty or invalid data
            if isempty(tableDataNumeric) || any(isnan(tableDataNumeric(:))) || size(tableDataNumeric,2)~=6
                % Show a message box with a subtly sarcastic error message
                msgbox('Unable to refresh preview because invalid numeric data was found.', 'You lose, fella.', 'error');
                hold(app.UIAxes, 'off');
                return;
            end

            % 3. Plot Lines
            X = [tableDataNumeric(:, 1), tableDataNumeric(:, 4)]';
            Y = [tableDataNumeric(:, 2), tableDataNumeric(:, 5)]';
            Z = [tableDataNumeric(:, 3), tableDataNumeric(:, 6)]';

            line(app.UIAxes, X, Y, Z, 'LineWidth', 2);  % Increased line width to 2
            
            energ_idx = str2double(app.SourceconductorDropDown.Value);
            % Check if the energization index is valid
            if ~isempty(energ_idx) && energ_idx > 0 && energ_idx <= size(tableDataNumeric, 1)
                % Extract the coordinates for the conductor specified by energ_idx
                x_energ = tableDataNumeric(energ_idx, 1);
                y_energ = tableDataNumeric(energ_idx, 2);
                z_energ = tableDataNumeric(energ_idx, 3);

                % Add the scatter3D marker
                scatter3(app.UIAxes, x_energ, y_energ, z_energ, 100, 'vr', 'filled');  % 100 is the marker size, 'r' is the color
                text(app.UIAxes, x_energ+0.25, y_energ+0.25, z_energ+0.25, 'Energization', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');
            end
            
            ret_idx = str2double(app.ReturnDropDown.Value);
            % Check if the return index is valid
            if ~isempty(ret_idx) && ret_idx > 0 && ret_idx <= size(tableDataNumeric, 1)
                % Extract the coordinates for the conductor specified by energ_idx
                x_ret = tableDataNumeric(ret_idx, 1);
                y_ret = tableDataNumeric(ret_idx, 2);
                z_ret = tableDataNumeric(ret_idx, 3);

                % Add the scatter3D marker
                scatter3(app.UIAxes, x_ret, y_ret, z_ret, 100, '^b', 'filled');  % 100 is the marker size, 'b' is the color
                text(app.UIAxes, x_ret+0.25, y_ret+0.25, z_ret+0.25, 'Return neutral', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');
            end


            % Add sampling points grid
            % Define the parameters
            delta = app.DeltaEditField.Value;
            gridoffset = app.GridoffsetmEditField.Value;

            % Calculate bounds based on user inputs
            if min(X) == max(X)  % Single vertical conductor
                x0 = mean(mean(X)) - gridoffset;
                x1 = mean(mean(X)) + gridoffset;
            else
                x0 = min(min(X)) - gridoffset;
                x1 = max(max(X)) + gridoffset;
            end

            if min(Y) == max(Y)  % Single vertical conductor
                y0 = mean(mean(Y)) - gridoffset;
                y1 = mean(mean(Y)) + gridoffset;
            else
                y0 = min(min(Y)) - gridoffset;
                y1 = max(max(Y)) + gridoffset;
            end

            % Generate the grid lines
            xGrid = linspace(x0, x1, ceil((x1 - x0) / delta));
            yGrid = linspace(y0, y1, ceil((y1 - y0) / delta));

            % Plot the grid lines along X
            for x = xGrid
                line(app.UIAxes, [x, x], [y0, y1], [0, 0], 'LineWidth', 0.5, 'Color', [0.5, 0.5, 0.5], 'LineStyle', '-');
            end

            % Plot the grid lines along Y
            for y = yGrid
                line(app.UIAxes, [x0, x1], [y, y], [0, 0], 'LineWidth', 0.5, 'Color', [0.5, 0.5, 0.5], 'LineStyle', '-');
            end
            
            
            % Fix axis proportions
            h=-app.ToplayerthicknessmEditField.Value;
            app.UIAxes.DataAspectRatio = [1 1 .25];

            % Get the current axis limits
            xLimits = xlim(app.UIAxes);
            yLimits = ylim(app.UIAxes);

            % Create patch vertices and faces
            vertices = [xLimits(1) yLimits(1) 0; xLimits(2) yLimits(1) 0; xLimits(2) yLimits(2) 0; xLimits(1) yLimits(2) 0];
            vertices_toplayer = [xLimits(1) yLimits(1) h; xLimits(2) yLimits(1) h; xLimits(2) yLimits(2) h; xLimits(1) yLimits(2) h];
            faces = [1 2 3 4];

            % Plot the patch
            patch(app.UIAxes, 'Faces', faces, 'Vertices', vertices, 'FaceColor', [0.6 0.4 0.1], 'FaceAlpha', 0.1);
            patch(app.UIAxes, 'Faces', faces, 'Vertices', vertices_toplayer, 'FaceColor', [0.6 0.4 0.1], 'FaceAlpha', 0.1);
            
            % Add a label
            meanX = mean(X(:));
            meanY = mean(Y(:));
            text(app.UIAxes, meanX, meanY, 0.25, 'Earth surface', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');
            text(app.UIAxes, meanX, meanY, h+0.25, 'Top layer boundary', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');


            % 4. Enable Camera Toolbar
            cameratoolbar('Show','SetMode','orbit');

            % Release the hold on the UIAxes
            hold(app.UIAxes, 'off');


        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            newrow={[],[],[],[],[],[],0,app.RadiusmEditField.Value,1,1};
            app.UITable.Data=[app.UITable.Data;newrow];
            redefRowLabels(app);
%             RefreshPreviewButtonPushed(app, event);
        end

        % Button pushed function: DeleterowButton
        function DeleterowButtonPushed(app, event)
            delrow=(app.row2Delete.Value);
            if ~isempty(app.UITable.Data)
                if isnan(delrow)
                    app.UITable.Data(end,:)=[];
                else
                    app.UITable.Data(delrow,:)=[];
                end
                redefRowLabels(app);
                RefreshPreviewButtonPushed(app, event);
            end
        end

        % Button pushed function: SanitycheckButton
        function SanitycheckButtonPushed(app, event)
            
            [isgood, errorMsg, rownum] = validateDataTable(app);
            if ~isgood
                if ~isempty(errorMsg)
                    if isempty(rownum)
                        msg=sprintf('Data entered by the user defies the laws of physics. ');
                    else
                        msg=sprintf('Data at row %d defies the laws of physics. ',rownum);
                    end
                    msgbox([msg, errorMsg], 'You lose, fella.', 'error');
                    return;
                end
            else
                msgbox(['Input data looks good!'], 'You win, fella.', 'help');
            end

        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            saveAllData(app);
        end

        % Button pushed function: LoadWorkdirButton
        function LoadWorkdirButtonPushed(app, event)
            path=uigetdir(pwd,'Choose work directory');
            app.GroundCalc.Visible = 'off';
            app.GroundCalc.Visible = 'on';
            if path ~=0
                app.WorkdirectoryEditField.Value=fullfile(path);
            end
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            app.GroundCalc.Visible = 'off';
            app.GroundCalc.Visible = 'on';
            if file ~= 0
                fname=fullfile(path,file);
                try
                    load(fname,'app_data');
                    writeAllAppValues(app,app_data);
                    %                     setColFormats(app);
%                     redefFormulas(app);
                    RefreshPreviewButtonPushed(app, event);
                catch
                    f = msgbox('Unable to load project file!','You lose, fella.','error');
                    return;
                end
            end
        end

        % Button pushed function: Convert2RadiusButton
        function Convert2RadiusButtonPushed(app, event)
            src_unit=app.RetardedUnitDropDown.Value;
            src_val=app.RetardedValueEditField.Value;
            thisval=str2double(src_val);
            if isnan(thisval)
                try
                    thisval=eval(src_val);
                    if ~isnumeric(thisval)
                        f = msgbox('Unable to parse retarded-units input.','You lose, fella.','error');
                        return
                    end
                catch
                    f = msgbox('Unable to parse retarded-units input.','You lose, fella.','error');
                    return;
                end
            end

            switch src_unit

                case 'mm²'
                    app.RadiusmEditField.Value=sqrt(thisval/pi)/1000;
                case 'in'
                    app.RadiusmEditField.Value=(0.0254*thisval/2/1000);
                case 'AWG'
                    app.RadiusmEditField.Value=0.127^((36-thisval)/39)/2/1000;
                case 'cmils'
                    %convert this shitfest to something close to reasonable
                    stupid_area=5.066e-10*thisval;
                    app.RadiusmEditField.Value=sqrt(stupid_area/pi);
            end
        end

        % Button pushed function: ClearmessagesButton
        function ClearmessagesButtonPushed(app, event)
            app.ProgrammessagesTextArea.Value='';
        end

        % Button pushed function: RecalculatesafevaluesButton
        function RecalculatesafevaluesButtonPushed(app, event)
            t_fault=app.FaultdurationmsEditField.Value/1000;
            rho_cover=app.CoverlayerresistivitymEditField.Value;
            h_cover=app.CoverlayerthicknesscmEditField.Value / 100;
            rho_top=app.ToplayerresistivitymEditField.Value;

            Ib=0.116/sqrt(t_fault);

            if(h_cover==0)
                Cs=1;
                R2Fs=6*Cs*rho_top;
                R2Fp=1.5*Cs*rho_top;
            else
                Cs=1-(0.106*((1-(rho_top/rho_cover))/(2*h_cover+0.106)));
                R2Fs=6*Cs*rho_cover;
                R2Fp=1.5*Cs*rho_cover;
            end

            Rb=1000;
            Ep_max=(Rb+R2Fs)*Ib;
            Et_max=(Rb+R2Fp)*Ib;

            app.ProgrammessagesTextArea.Value{end+1} = [sprintf('\n\nSAFETY LIMITS ASSESSMENT:\n---------------------------------------------\n')];
            app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Top layer resistivity: %g ohm.m\n', rho_top)];
            app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Fault clearance time: %g s\n', t_fault)];
            app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Cover material resistivity: %g ohm.m\n', rho_cover)];
            app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Cover layer thickness: %g m\n', h_cover)];
            app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Maximum allowable touch voltage: %g V\n', Et_max)];
            app.ProgrammessagesTextArea.Value{end+1} = [sprintf('Maximum allowable step voltage: %g V\n\n\n', Ep_max)];

            if app.RefreshplotsButton.Enable
                app.RedefineplotsButtonPushed;
            end


        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
            if ~isempty(app.UITable.Data)
                indices = event.Indices;
                if ~isempty(indices)
                    selectedRow = indices(1);
                    app.row2Delete.Value=selectedRow;
                end

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GroundCalc = uifigure('Visible', 'off');
            app.GroundCalc.Name = 'GroundCalc - Grounding Grid Calculator';
            app.GroundCalc.Icon = fullfile(pathToMLAPP, 'groundcalc_icn.png');
            app.TabGroup = uitabgroup(app.GroundCalc);
            app.SystemTab = uitab(app.TabGroup);
            app.SystemTab.Title = 'System';
            app.UITable = uitable(app.SystemTab);
            app.UITable.ColumnName = {'x_start'; 'y_start'; 'z_start'; 'x_end'; 'y_end'; 'z_end'; 'rad_in'; 'rad_ext'; 'rho_r'; 'mu_r'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Load2TableButton = uibutton(app.SystemTab, 'push');
            app.Load2TableButton.ButtonPushedFcn = createCallbackFcn(app, @Load2TableButtonPushed, true);
            app.Load2TableButton.Text = '🪄 Build conductor table from file';
            app.AddrowButton = uibutton(app.SystemTab, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Text = '➕ Add row';
            app.DeleterowButton = uibutton(app.SystemTab, 'push');
            app.DeleterowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterowButtonPushed, true);
            app.DeleterowButton.Text = '❌ Delete row';
            app.CurrentrowLabel = uilabel(app.SystemTab);
            app.CurrentrowLabel.Text = 'Current row:';
            app.row2Delete = uieditfield(app.SystemTab, 'numeric');
            app.row2Delete.ValueDisplayFormat = '%.0f';
            app.row2Delete.Editable = 'off';
            app.SanitycheckButton = uibutton(app.SystemTab, 'push');
            app.SanitycheckButton.ButtonPushedFcn = createCallbackFcn(app, @SanitycheckButtonPushed, true);
            app.SanitycheckButton.Text = '🩺 Sanity check';
            app.GeneralPanel = uipanel(app.SystemTab);
            app.GeneralPanel.Title = 'General';
            app.LoadWorkdirButton = uibutton(app.GeneralPanel, 'push');
            app.LoadWorkdirButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWorkdirButtonPushed, true);
            app.LoadWorkdirButton.Text = '📂';
            app.WorkdirectoryEditFieldLabel = uilabel(app.GeneralPanel);
            app.WorkdirectoryEditFieldLabel.Text = 'Work directory:';
            app.WorkdirectoryEditField = uieditfield(app.GeneralPanel, 'text');
            app.JobIDLabel = uilabel(app.GeneralPanel);
            app.JobIDLabel.Text = 'JobID:';
            app.JobIDEditField = uieditfield(app.GeneralPanel, 'text');
            app.ProjectdescriptionLabel = uilabel(app.GeneralPanel);
            app.ProjectdescriptionLabel.Text = 'Project description:';
            app.ProjectdescriptionTextArea = uitextarea(app.GeneralPanel);
            app.ConductordataPanel = uipanel(app.SystemTab);
            app.ConductordataPanel.Title = 'Conductor data';
            app.LoadCondButton = uibutton(app.ConductordataPanel, 'push');
            app.LoadCondButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCondButtonPushed, true);
            app.LoadCondButton.Text = '📂';
            app.SourcefileEditFieldLabel = uilabel(app.ConductordataPanel);
            app.SourcefileEditFieldLabel.Text = 'Source [file]:';
            app.SourcefileEditField = uieditfield(app.ConductordataPanel, 'text');
            app.SourcefileEditField.Editable = 'off';
            app.SourcefileEditField.Tooltip = {'DXF, CSV or text file containing the 3D coordinates [x, y, z] of the start point and end point of each conductor, respectively, in the format [x_s, y_s, z_s, x_e, y_e, z_e]. Each line of the CSV file may also contain an additional (optional) column rad_ext, in meters.'};
            app.RadiusmEditField = uieditfield(app.ConductordataPanel, 'numeric');
            app.RadiusmEditField.Limits = [0 Inf];
            app.RadiusmEditField.Value = 0.00549903;
            app.BasemLabel = uilabel(app.ConductordataPanel);
            app.BasemLabel.Text = 'Base ρ [Ωm]:';
            app.BasemEditField = uieditfield(app.ConductordataPanel, 'numeric');
            app.BasemEditField.Limits = [0 Inf];
            app.BasemEditField.Editable = 'off';
            app.BasemEditField.Tooltip = {'Base conductor resistivity to determine the relative rho_r in the conductor table.'};
            app.BasemEditField.Value = 1.724e-08;
            app.defaultannealedcopperLabel = uilabel(app.ConductordataPanel);
            app.defaultannealedcopperLabel.Text = '(default: annealed copper)';
            app.SubdivisionlengthmEditFieldLabel = uilabel(app.ConductordataPanel);
            app.SubdivisionlengthmEditFieldLabel.Text = 'Subdivision length [m]:';
            app.SubdivisionlengthmEditField = uieditfield(app.ConductordataPanel, 'numeric');
            app.SubdivisionlengthmEditField.Limits = [0 Inf];
            app.SubdivisionlengthmEditField.Tooltip = {'Maximum length of each conductor subdivision. MAY IMPACT COMPUTATION SPEED.'};
            app.SubdivisionlengthmEditField.Value = 1;
            app.SourceconductorDropDownLabel = uilabel(app.ConductordataPanel);
            app.SourceconductorDropDownLabel.Text = 'Source conductor: #';
            app.SourceconductorDropDown = uidropdown(app.ConductordataPanel);
            app.SourceconductorDropDown.Items = {};
            app.SourceconductorDropDown.Tooltip = {'Energization current will be applied to the start coordinates of this conductor. '};
            app.SourceconductorDropDown.Value = {};
            app.Convert2RadiusButton = uibutton(app.ConductordataPanel, 'push');
            app.Convert2RadiusButton.ButtonPushedFcn = createCallbackFcn(app, @Convert2RadiusButtonPushed, true);
            app.Convert2RadiusButton.Tooltip = {'Hit to convert from retarded-units into something that makes sense in the real world.'};
            app.Convert2RadiusButton.Text = '➡';
            app.RetardedUnitDropDown = uidropdown(app.ConductordataPanel);
            app.RetardedUnitDropDown.Items = {'mm²', 'AWG', 'in', 'cmils'};
            app.RetardedUnitDropDown.Value = 'mm²';
            app.RadiusmEditFieldLabel = uilabel(app.ConductordataPanel);
            app.RadiusmEditFieldLabel.Text = 'Radius [m]:';
            app.RetardedValueEditField = uieditfield(app.ConductordataPanel, 'text');
            app.ReturnDropDownLabel = uilabel(app.ConductordataPanel);
            app.ReturnDropDownLabel.Text = 'Return: #';
            app.ReturnDropDown = uidropdown(app.ConductordataPanel);
            app.ReturnDropDown.Items = {};
            app.ReturnDropDown.Tooltip = {'Return current will flow into the grid at the start coordinates of this conductor (e.g. Y-grounded neutral point).'};
            app.ReturnDropDown.Value = {};
            app.ComputationsTab = uitab(app.TabGroup);
            app.ComputationsTab.Title = 'Computations';
            app.SafelimitsPanel = uipanel(app.ComputationsTab);
            app.SafelimitsPanel.Title = 'Safe limits';
            app.CoverlayerresistivitymLabel = uilabel(app.SafelimitsPanel);
            app.CoverlayerresistivitymLabel.Text = 'Cover layer resistivity [Ωm]:';
            app.CoverlayerresistivitymEditField = uieditfield(app.SafelimitsPanel, 'numeric');
            app.CoverlayerresistivitymEditField.Limits = [0 Inf];
            app.CoverlayerresistivitymEditField.Value = 3000;
            app.CoverlayerthicknesscmEditFieldLabel = uilabel(app.SafelimitsPanel);
            app.CoverlayerthicknesscmEditFieldLabel.Text = 'Cover layer thickness [cm]:';
            app.CoverlayerthicknesscmEditField = uieditfield(app.SafelimitsPanel, 'numeric');
            app.CoverlayerthicknesscmEditField.Limits = [0 Inf];
            app.FaultdurationmsEditFieldLabel = uilabel(app.SafelimitsPanel);
            app.FaultdurationmsEditFieldLabel.Text = 'Fault duration [ms]:';
            app.FaultdurationmsEditField = uieditfield(app.SafelimitsPanel, 'numeric');
            app.FaultdurationmsEditField.Limits = [0 Inf];
            app.FaultdurationmsEditField.Value = 1000;
            app.RequestplotsPanel = uipanel(app.ComputationsTab);
            app.RequestplotsPanel.Title = 'Request plots';
            app.SurfacepotentialsCheckBox = uicheckbox(app.RequestplotsPanel);
            app.SurfacepotentialsCheckBox.Text = 'Surface potentials';
            app.SurfacepotentialsCheckBox.Value = true;
            app.CurrentdistributionCheckBox = uicheckbox(app.RequestplotsPanel);
            app.CurrentdistributionCheckBox.Text = 'Current distribution';
            app.CurrentdistributionCheckBox.Value = true;
            app.TouchvoltagesCheckBox = uicheckbox(app.RequestplotsPanel);
            app.TouchvoltagesCheckBox.Text = 'Touch voltages';
            app.TouchvoltagesCheckBox.Value = true;
            app.StepvoltagesCheckBox = uicheckbox(app.RequestplotsPanel);
            app.StepvoltagesCheckBox.Text = 'Step voltages';
            app.StepvoltagesCheckBox.Value = true;
            app.RefreshplotsButton = uibutton(app.RequestplotsPanel, 'push');
            app.RefreshplotsButton.ButtonPushedFcn = createCallbackFcn(app, @RedefineplotsButtonPushed, true);
            app.RefreshplotsButton.Enable = 'off';
            app.RefreshplotsButton.Text = '🔄';
            app.PointspacingmLabel = uilabel(app.RequestplotsPanel);
            app.PointspacingmLabel.Text = 'Δx, Δy [m]:';
            app.DeltaEditField = uieditfield(app.RequestplotsPanel, 'numeric');
            app.DeltaEditField.Limits = [0 Inf];
            app.DeltaEditField.Tooltip = {'Grid discretization step for surface plots, i.e. quantities will be sampled every Δx, Δy m.'};
            app.DeltaEditField.Value = 1;
            app.GridoffsetmEditFieldLabel = uilabel(app.RequestplotsPanel);
            app.GridoffsetmEditFieldLabel.Text = 'Grid offset [m]:';
            app.GridoffsetmEditField = uieditfield(app.RequestplotsPanel, 'numeric');
            app.GridoffsetmEditField.Limits = [0 Inf];
            app.GridoffsetmEditField.Tooltip = {'Extra space to be added around the grid boundaries for surface plots.'};
            app.GridoffsetmEditField.Value = 5;
            app.MiscPanel = uipanel(app.ComputationsTab);
            app.MiscPanel.Title = 'Misc';
            app.SuppressmessagesCheckBox = uicheckbox(app.MiscPanel);
            app.SuppressmessagesCheckBox.Text = 'Suppress messages';
            app.ShowgridCheckBox = uicheckbox(app.MiscPanel);
            app.ShowgridCheckBox.Text = 'Show grid';
            app.ShowgridCheckBox.Value = true;
            app.SavetomatfileCheckBox = uicheckbox(app.MiscPanel);
            app.SavetomatfileCheckBox.Text = 'Save to .mat file';
            app.SavetomatfileCheckBox.Value = true;
            app.StartButton = uibutton(app.ComputationsTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '⚡ Start!';
            app.ProgrammessagesTextAreaLabel = uilabel(app.ComputationsTab);
            app.ProgrammessagesTextAreaLabel.Text = 'Program messages:';
            app.ProgrammessagesTextArea = uitextarea(app.ComputationsTab);
            app.ProgrammessagesTextArea.Editable = 'off';
            app.ClearmessagesButton = uibutton(app.ComputationsTab, 'push');
            app.ClearmessagesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearmessagesButtonPushed, true);
            app.ClearmessagesButton.Text = '🧹 Clear messages';
            app.RecalculatesafevaluesButton = uibutton(app.ComputationsTab, 'push');
            app.RecalculatesafevaluesButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculatesafevaluesButtonPushed, true);
            app.RecalculatesafevaluesButton.Text = '⚠️ Recalculate safe values ';
            app.ComputationengineoptionsPanel = uipanel(app.ComputationsTab);
            app.ComputationengineoptionsPanel.Title = 'Computation engine options';
            app.AssumeequipotentialgridCheckBox = uicheckbox(app.ComputationengineoptionsPanel);
            app.AssumeequipotentialgridCheckBox.Tooltip = {'This option enforces the ancient assumption of equipotential conductors, which provides reasonable results for small to medium sized grids under low frequencies. But hey, how about considering that physics does exist?'};
            app.AssumeequipotentialgridCheckBox.Text = 'Assume equipotential grid';
            app.UsepointsourcemodelCheckBox = uicheckbox(app.ComputationengineoptionsPanel);
            app.UsepointsourcemodelCheckBox.Tooltip = {'This option enforces the basic formulation of the Green''s functions for a point source (segmentation method), rather than the microsegmentation-integration method. Use (at your own risk) to check the model in case of apparent numerical instabilities. '};
            app.UsepointsourcemodelCheckBox.Text = 'Use point source model';
            app.RebuildcircuitmodelfromscratchCheckBox = uicheckbox(app.ComputationengineoptionsPanel);
            app.RebuildcircuitmodelfromscratchCheckBox.Tooltip = {'This option enforces the program to reprocess the entire geometry again, in case data from a previous input session is found.'};
            app.RebuildcircuitmodelfromscratchCheckBox.Text = 'Rebuild circuit model from scratch';
            app.RebuildcircuitmodelfromscratchCheckBox.Value = true;
            app.FrequencyHzEditFieldLabel = uilabel(app.ComputationengineoptionsPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency [Hz]:';
            app.FrequencyHzEditField = uieditfield(app.ComputationengineoptionsPanel, 'numeric');
            app.FrequencyHzEditField.Limits = [0 Inf];
            app.FrequencyHzEditField.Value = 60;
            app.PreviewPanel = uipanel(app.GroundCalc);
            app.PreviewPanel.Title = 'Preview';
            app.UIAxes = uiaxes(app.PreviewPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RefreshPreview = uibutton(app.PreviewPanel, 'push');
            app.RefreshPreview.ButtonPushedFcn = createCallbackFcn(app, @RefreshPreviewButtonPushed, true);
            app.RefreshPreview.Text = '🔄 Refresh';
            app.SoilresistivityPanel = uipanel(app.GroundCalc);
            app.SoilresistivityPanel.Title = 'Soil resistivity';
            app.ToplayerresistivitymLabel = uilabel(app.SoilresistivityPanel);
            app.ToplayerresistivitymLabel.Text = 'Top layer resistivity [Ωm]:';
            app.ToplayerresistivitymEditField = uieditfield(app.SoilresistivityPanel, 'numeric');
            app.ToplayerresistivitymEditField.Limits = [0 Inf];
            app.ToplayerresistivitymEditField.Value = 1000;
            app.BottomlayerresistivitymLabel = uilabel(app.SoilresistivityPanel);
            app.BottomlayerresistivitymLabel.Text = 'Bottom layer resistivity [Ωm]:';
            app.BottomlayerresistivitymEditField = uieditfield(app.SoilresistivityPanel, 'numeric');
            app.BottomlayerresistivitymEditField.Limits = [0 Inf];
            app.BottomlayerresistivitymEditField.Value = 100;
            app.ToplayerthicknessmEditFieldLabel = uilabel(app.SoilresistivityPanel);
            app.ToplayerthicknessmEditFieldLabel.Text = 'Top layer thickness [m]:';
            app.ToplayerthicknessmEditField = uieditfield(app.SoilresistivityPanel, 'numeric');
            app.ToplayerthicknessmEditField.Limits = [0 Inf];
            app.ToplayerthicknessmEditField.Value = 1;
            app.SavedataButton = uibutton(app.GroundCalc, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = '💾 Save data';
            app.LoaddataButton = uibutton(app.GroundCalc, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = '📁 Load data';
            app.ExitButton = uibutton(app.GroundCalc, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '🚪 Exit';
            app.EnergizationcurrentAPanel = uipanel(app.GroundCalc);
            app.EnergizationcurrentAPanel.Title = 'Energization current [A]';
            app.I_im = uieditfield(app.EnergizationcurrentAPanel, 'numeric');
            app.I_im.Limits = [0 Inf];
            app.jLabel = uilabel(app.EnergizationcurrentAPanel);
            app.jLabel.Text = '+j';
            app.I_re = uieditfield(app.EnergizationcurrentAPanel, 'numeric');
            app.I_re.Limits = [0 Inf];
            app.I_re.Value = 3000;
            app.ReturncurrentAPanel = uipanel(app.GroundCalc);
            app.ReturncurrentAPanel.Title = 'Return current [A]';
            app.Iret_im = uieditfield(app.ReturncurrentAPanel, 'numeric');
            app.Iret_im.Limits = [0 Inf];
            app.jLabel_2 = uilabel(app.ReturncurrentAPanel);
            app.jLabel_2.Text = '+j';
            app.Iret_re = uieditfield(app.ReturncurrentAPanel, 'numeric');
            app.Iret_re.Limits = [0 Inf];
            % Show the figure after all components are created
            app.GroundCalc.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = groundcalc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GroundCalc)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GroundCalc)
        end
    end
end