classdef groundcalc_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GroundCalc                      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SystemgeometryTab               matlab.ui.container.Tab
        LoadCondButton                  matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        DefaultconductorradiusmEditFieldLabel  matlab.ui.control.Label
        DefaultconductorradiusmEditField  matlab.ui.control.NumericEditField
        ConductorcoordinatesfileEditFieldLabel  matlab.ui.control.Label
        ConductorcoordinatesfileEditField  matlab.ui.control.EditField
        PointspacingmLabel              matlab.ui.control.Label
        DeltaEditField                  matlab.ui.control.NumericEditField
        BufferEditFieldLabel            matlab.ui.control.Label
        BufferEditField                 matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        Load2TableButton                matlab.ui.control.Button
        AddrowButton                    matlab.ui.control.Button
        DeletelastrowButton             matlab.ui.control.Button
        RunTab                          matlab.ui.container.Tab
        SoilresistivityPanel            matlab.ui.container.Panel
        ToplayerresistivitymEditFieldLabel  matlab.ui.control.Label
        ToplayerresistivitymEditField   matlab.ui.control.NumericEditField
        BottomlayerresistivitymEditFieldLabel  matlab.ui.control.Label
        BottomlayerresistivitymEditField  matlab.ui.control.NumericEditField
        ToplayerthicknessmEditFieldLabel  matlab.ui.control.Label
        ToplayerthicknessmEditField     matlab.ui.control.NumericEditField
        PowersystemPanel                matlab.ui.container.Panel
        I_im                            matlab.ui.control.NumericEditField
        jLabel                          matlab.ui.control.Label
        I_re                            matlab.ui.control.NumericEditField
        FaultcurrentALabel              matlab.ui.control.Label
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        SafelimitsPanel                 matlab.ui.container.Panel
        CoverlayerresistivitymEditFieldLabel  matlab.ui.control.Label
        CoverlayerresistivitymEditField  matlab.ui.control.NumericEditField
        CoverlayerthicknesscmEditFieldLabel  matlab.ui.control.Label
        CoverlayerthicknesscmEditField  matlab.ui.control.NumericEditField
        FaultdurationmsEditFieldLabel   matlab.ui.control.Label
        FaultdurationmsEditField        matlab.ui.control.NumericEditField
        RequestplotsPanel               matlab.ui.container.Panel
        SurfacepotentialsCheckBox       matlab.ui.control.CheckBox
        CurrentdistributionCheckBox     matlab.ui.control.CheckBox
        TouchvoltagesCheckBox           matlab.ui.control.CheckBox
        StepvoltagesCheckBox            matlab.ui.control.CheckBox
        RefreshplotsButton              matlab.ui.control.Button
        MiscPanel                       matlab.ui.container.Panel
        SuppressmessagesCheckBox        matlab.ui.control.CheckBox
        ShowgridCheckBox                matlab.ui.control.CheckBox
        SavetomatfileCheckBox           matlab.ui.control.CheckBox
        StartButton                     matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
    end


    methods (Access = private)
    
        function [isgood, msg] = validateFilename(app)
            fname=app.ConductorcoordinatesfileEditField.Value;
            isgood=true;
            msg='';
            if  ~exist(fname, 'file')
                msg='Invalid file name!';
                isgood=false;
                return;
            end
        end
        
        function [results, msg] = loadFromFile(app,fname)
            results=[];
            msg=[];
            try
                data=importdata(fname);
            catch
                msg='File contains invalid (non-numerical) data!';
                return;
            end
            
%             if size(data,1)~=nrows || size(data,2)~=ncols
%                 msg=['Invalid array dimensions! File must contain ' num2str(nrows) ' row(s) x ' num2str(ncols) ' column(s).'];
%                 return;
%             end
            if isstruct(data)
                results=data.data;
            else
                results=data;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UITable.ColumnFormat = {'numeric'};
            app.UITable.ColumnFormat = repmat( {'numeric'}, 1, 7 );
            app.UITable.Data=cell(1,7);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: LoadCondButton
        function LoadCondButtonPushed(app, event)

            [file,path] = uigetfile({'*.txt';'*.csv'});
            app.GroundCalc.Visible = 'off';
            app.GroundCalc.Visible = 'on';
            if file ~=0
                fname=fullfile(path,file);
                app.ConductorcoordinatesfileEditField.Value=fname;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
%             [testfile,msg]=validateFilename(app);
%             if ~testfile
%                 f = msgbox(msg, 'You lose, fella.','error');
%                 return;
%             end
%             fname=app.ConductorcoordinatesfileEditField.Value;
            try
                cond_data=cell2mat(app.UITable.Data);
            end
            
            if ~exist('cond_data','var') 
                msg = 'Unable to parse conductor data. Check the system geometry table and make sure all fields contain valid values.';
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            
            if isempty(cond_data)
                msg = 'Unable to parse conductor data. Check the system geometry table and make sure all fields contain valid values.';
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            
            if size(cond_data,2) < 7
                msg = 'Unable to parse conductor data. Check the system geometry table and make sure all fields contain valid values.';
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            
            allz = [cond_data(:,3); cond_data(:,6)];
            
            if any(allz >= 0)
                msg = 'All conductor z-coordinates must be negative real values. Check the system geometry table and try again.';
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            
            delta=app.DeltaEditField.Value;
            buffer=app.BufferEditField.Value;
%             cond_radius=app.DefaultconductorradiusmEditField.Value;
            rho_top=app.ToplayerresistivitymEditField.Value;
            rho_bottom=app.BottomlayerresistivitymEditField.Value;
            h_top=app.ToplayerthicknessmEditField.Value;
            i_src=complex(app.I_re.Value,app.I_im.Value);
            t_fault=app.FaultdurationmsEditField.Value;
            rho_cover=app.CoverlayerresistivitymEditField.Value;
            h_cover=app.CoverlayerthicknesscmEditField.Value / 100;
            plot_surf=app.SurfacepotentialsCheckBox.Value;
            plot_curr=app.CurrentdistributionCheckBox.Value;
            plot_touch=app.TouchvoltagesCheckBox.Value;
            plot_step=app.StepvoltagesCheckBox.Value;
            plot_grid=app.ShowgridCheckBox.Value;
            supress_messages=app.SuppressmessagesCheckBox.Value;
            save_mat=app.SavetomatfileCheckBox.Value;
            try
                close all;
                clc;
                addpath('functions');
                runGroundCalcSolver(cond_data(:,1:6), delta, buffer, cond_data(:,7), rho_top, rho_bottom, h_top, i_src, t_fault, rho_cover, h_cover, plot_surf, plot_curr, plot_touch, plot_step, plot_grid, supress_messages, save_mat);
                app.RefreshplotsButton.Enable='on';
            catch
                f = msgbox('Error running GroundCalc solver!','You lose, fella.','error');
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            f = msgbox(sprintf('Provide the path to a CSV or text file containing the 3D coordinates [x, y, z] of the start point and end point of each conductor, respectively, in the format [x_s, y_s, z_s, x_e, y_e, z_e]. Each line of the CSV file may also contain an additional (optional) column cond_radius, in meters. \n\nField Δ is the grid discretization step for surface plots.\n\nBuffer is a multiple of Δ that defines the extra space to be added to the grid boundaries for surface plots.'),'Help','help');
        end

        % Button pushed function: RefreshplotsButton
        function RedefineplotsButtonPushed(app, event)
            t_fault=app.FaultdurationmsEditField.Value/1000;
            rho_cover=app.CoverlayerresistivitymEditField.Value;
            h_cover=app.CoverlayerthicknesscmEditField.Value / 100;
            rho_top=app.ToplayerresistivitymEditField.Value;
            
           
            Ib=0.116/sqrt(t_fault);
            
            if(h_cover==0)
                Cs=1;
                R2Fs=6*Cs*rho_top;
                R2Fp=1.5*Cs*rho_top;
            else
                Cs=1-(0.106*((1-(rho_top/rho_cover))/(2*h_cover+0.106)));
                R2Fs=6*Cs*rho_cover;
                R2Fp=1.5*Cs*rho_cover;
            end
            Rb=1000;
            Ep_max=(Rb+R2Fs)*Ib;
            Et_max=(Rb+R2Fp)*Ib;
            
            figure(2);
            vmin=Et_max;
            vmax=max(caxis);
            if vmax<vmin
                vmax=2*vmin;
            end
            caxis([vmin vmax]);
            title(sprintf('Touch voltages distribution, E_{t,lim} = %1.0f V',Et_max));
            
            figure(3);
            vmin=Ep_max;
            vmax=max(caxis);
            if vmax<vmin
                vmax=2*vmin;
            end
            caxis([vmin vmax]);
            title(sprintf('Step voltages distribution, E_{p,lim} = %1.0f V',Ep_max));
        end

        % Button pushed function: Load2TableButton
        function Load2TableButtonPushed(app, event)
            [testfile,msg]=validateFilename(app);
            if ~testfile
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            end
            fname=app.ConductorcoordinatesfileEditField.Value;
            
            [fdata,msg]=loadFromFile(app,fname);
            
            if isempty(fdata)
                f = msgbox(msg, 'You lose, fella.','error');
                return;
            else
                nrows=size(fdata,1);
                ncols=size(fdata,2);
                if ncols==6
                    std_rad=app.DefaultconductorradiusmEditField.Value;
                    fdata = [fdata std_rad*ones(1,nrows)'];
                elseif ncols==7
                    % do nothing
                else
                    msg = 'Invalid file structure - input file must contain 6 or 7 numeric columns!';
                    f = msgbox(msg, 'You lose, fella.','error');
                    return;
                end
                app.UITable.Data=num2cell(fdata);
            end
            
        end

        % Button pushed function: AddrowButton
        function AddrowButtonPushed(app, event)
            app.UITable.Data(end+1,:)=cell(1,7);
        end

        % Button pushed function: DeletelastrowButton
        function DeletelastrowButtonPushed(app, event)
            app.UITable.Data(end,:)=[];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GroundCalc = uifigure;
            app.GroundCalc.Name = 'GroundCalc - A Very Simple Grounding Grid Calculator';
            app.TabGroup = uitabgroup(app.GroundCalc);
            app.SystemgeometryTab = uitab(app.TabGroup);
            app.SystemgeometryTab.Title = 'System geometry';
            app.LoadCondButton = uibutton(app.SystemgeometryTab, 'push');
            app.LoadCondButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCondButtonPushed, true);
            app.LoadCondButton.Text = '';
            app.HelpButton = uibutton(app.SystemgeometryTab, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.DefaultconductorradiusmEditFieldLabel = uilabel(app.SystemgeometryTab);
            app.DefaultconductorradiusmEditFieldLabel.Text = 'Default conductor radius [m]:';
            app.DefaultconductorradiusmEditField = uieditfield(app.SystemgeometryTab, 'numeric');
            app.DefaultconductorradiusmEditField.Limits = [0 Inf];
            app.DefaultconductorradiusmEditField.Value = 0.0067056;
            app.ConductorcoordinatesfileEditFieldLabel = uilabel(app.SystemgeometryTab);
            app.ConductorcoordinatesfileEditFieldLabel.Text = 'Conductor coordinates [file]:';
            app.ConductorcoordinatesfileEditField = uieditfield(app.SystemgeometryTab, 'text');
            app.ConductorcoordinatesfileEditField.Editable = 'off';
            app.PointspacingmLabel = uilabel(app.SystemgeometryTab);
            app.PointspacingmLabel.Text = 'Point spacing Δ [m]:';
            app.DeltaEditField = uieditfield(app.SystemgeometryTab, 'numeric');
            app.DeltaEditField.Limits = [0 Inf];
            app.DeltaEditField.Value = 0.5;
            app.BufferEditFieldLabel = uilabel(app.SystemgeometryTab);
            app.BufferEditFieldLabel.Text = 'Buffer:';
            app.BufferEditField = uieditfield(app.SystemgeometryTab, 'numeric');
            app.BufferEditField.Limits = [0 Inf];
            app.BufferEditField.Value = 10;
            app.UITable = uitable(app.SystemgeometryTab);
            app.UITable.ColumnName = {'x_start'; 'y_start'; 'z_start'; 'x_end'; 'y_end'; 'z_end'; 'radius'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.Load2TableButton = uibutton(app.SystemgeometryTab, 'push');
            app.Load2TableButton.ButtonPushedFcn = createCallbackFcn(app, @Load2TableButtonPushed, true);
            app.Load2TableButton.Text = '⏩ Build conductor table from file';
            app.AddrowButton = uibutton(app.SystemgeometryTab, 'push');
            app.AddrowButton.ButtonPushedFcn = createCallbackFcn(app, @AddrowButtonPushed, true);
            app.AddrowButton.Text = '✚ Add row';
            app.DeletelastrowButton = uibutton(app.SystemgeometryTab, 'push');
            app.DeletelastrowButton.ButtonPushedFcn = createCallbackFcn(app, @DeletelastrowButtonPushed, true);
            app.DeletelastrowButton.Text = '✘ Delete last row';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.SoilresistivityPanel = uipanel(app.RunTab);
            app.SoilresistivityPanel.Title = 'Soil resistivity';
            app.ToplayerresistivitymEditFieldLabel = uilabel(app.SoilresistivityPanel);
            app.ToplayerresistivitymEditFieldLabel.Text = 'Top layer resistivity [Ω.m]:';
            app.ToplayerresistivitymEditField = uieditfield(app.SoilresistivityPanel, 'numeric');
            app.ToplayerresistivitymEditField.Limits = [0 Inf];
            app.ToplayerresistivitymEditField.Value = 1000;
            app.BottomlayerresistivitymEditFieldLabel = uilabel(app.SoilresistivityPanel);
            app.BottomlayerresistivitymEditFieldLabel.Text = 'Bottom layer resistivity [Ω.m]:';
            app.BottomlayerresistivitymEditField = uieditfield(app.SoilresistivityPanel, 'numeric');
            app.BottomlayerresistivitymEditField.Limits = [0 Inf];
            app.BottomlayerresistivitymEditField.Value = 100;
            app.ToplayerthicknessmEditFieldLabel = uilabel(app.SoilresistivityPanel);
            app.ToplayerthicknessmEditFieldLabel.Text = 'Top layer thickness [m]:';
            app.ToplayerthicknessmEditField = uieditfield(app.SoilresistivityPanel, 'numeric');
            app.ToplayerthicknessmEditField.Limits = [0 Inf];
            app.ToplayerthicknessmEditField.Value = 1;
            app.PowersystemPanel = uipanel(app.RunTab);
            app.PowersystemPanel.Title = 'Power system';
            app.I_im = uieditfield(app.PowersystemPanel, 'numeric');
            app.I_im.Limits = [0 Inf];
            app.jLabel = uilabel(app.PowersystemPanel);
            app.jLabel.Text = '+j';
            app.I_re = uieditfield(app.PowersystemPanel, 'numeric');
            app.I_re.Limits = [0 Inf];
            app.I_re.Value = 3000;
            app.FaultcurrentALabel = uilabel(app.PowersystemPanel);
            app.FaultcurrentALabel.Text = 'Fault current [A]:';
            app.FrequencyHzEditFieldLabel = uilabel(app.PowersystemPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency [Hz]:';
            app.FrequencyHzEditField = uieditfield(app.PowersystemPanel, 'numeric');
            app.FrequencyHzEditField.Limits = [0 Inf];
            app.FrequencyHzEditField.Editable = 'off';
            app.FrequencyHzEditField.Value = 60;
            app.SafelimitsPanel = uipanel(app.RunTab);
            app.SafelimitsPanel.Title = 'Safe limits';
            app.CoverlayerresistivitymEditFieldLabel = uilabel(app.SafelimitsPanel);
            app.CoverlayerresistivitymEditFieldLabel.Text = 'Cover layer resistivity [Ω.m]:';
            app.CoverlayerresistivitymEditField = uieditfield(app.SafelimitsPanel, 'numeric');
            app.CoverlayerresistivitymEditField.Limits = [0 Inf];
            app.CoverlayerresistivitymEditField.Value = 3000;
            app.CoverlayerthicknesscmEditFieldLabel = uilabel(app.SafelimitsPanel);
            app.CoverlayerthicknesscmEditFieldLabel.Text = 'Cover layer thickness [cm]:';
            app.CoverlayerthicknesscmEditField = uieditfield(app.SafelimitsPanel, 'numeric');
            app.CoverlayerthicknesscmEditField.Limits = [0 Inf];
            app.FaultdurationmsEditFieldLabel = uilabel(app.SafelimitsPanel);
            app.FaultdurationmsEditFieldLabel.Text = 'Fault duration [ms]:';
            app.FaultdurationmsEditField = uieditfield(app.SafelimitsPanel, 'numeric');
            app.FaultdurationmsEditField.Limits = [0 Inf];
            app.FaultdurationmsEditField.Value = 1000;
            app.RequestplotsPanel = uipanel(app.RunTab);
            app.RequestplotsPanel.Title = 'Request plots';
            app.SurfacepotentialsCheckBox = uicheckbox(app.RequestplotsPanel);
            app.SurfacepotentialsCheckBox.Text = 'Surface potentials';
            app.SurfacepotentialsCheckBox.Value = true;
            app.CurrentdistributionCheckBox = uicheckbox(app.RequestplotsPanel);
            app.CurrentdistributionCheckBox.Text = 'Current distribution';
            app.CurrentdistributionCheckBox.Value = true;
            app.TouchvoltagesCheckBox = uicheckbox(app.RequestplotsPanel);
            app.TouchvoltagesCheckBox.Text = 'Touch voltages';
            app.TouchvoltagesCheckBox.Value = true;
            app.StepvoltagesCheckBox = uicheckbox(app.RequestplotsPanel);
            app.StepvoltagesCheckBox.Text = 'Step voltages';
            app.StepvoltagesCheckBox.Value = true;
            app.RefreshplotsButton = uibutton(app.RequestplotsPanel, 'push');
            app.RefreshplotsButton.ButtonPushedFcn = createCallbackFcn(app, @RedefineplotsButtonPushed, true);
            app.RefreshplotsButton.Enable = 'off';
            app.RefreshplotsButton.Text = '⭮';
            app.MiscPanel = uipanel(app.RunTab);
            app.MiscPanel.Title = 'Misc';
            app.SuppressmessagesCheckBox = uicheckbox(app.MiscPanel);
            app.SuppressmessagesCheckBox.Text = 'Suppress messages';
            app.SuppressmessagesCheckBox.Value = true;
            app.ShowgridCheckBox = uicheckbox(app.MiscPanel);
            app.ShowgridCheckBox.Text = 'Show grid';
            app.ShowgridCheckBox.Value = true;
            app.SavetomatfileCheckBox = uicheckbox(app.MiscPanel);
            app.SavetomatfileCheckBox.Text = 'Save to .mat file';
            app.SavetomatfileCheckBox.Value = true;
            app.StartButton = uibutton(app.RunTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start!';
            app.ExitButton = uibutton(app.RunTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = groundcalc_main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GroundCalc)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GroundCalc)
        end
    end
end